classdef BarcodeReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MenuLabel                     matlab.ui.control.Label
        openfoodfactswebpageCheckBox  matlab.ui.control.CheckBox
        LoadimageButton               matlab.ui.control.Button
        StartcameraButton             matlab.ui.control.Button
        StopcameraButton              matlab.ui.control.Button
        CenterPanel                   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        EAN13BarcodeReaderLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        BarcodeLabel                  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ResultLabel                   matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        TextArea                      matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        i_bw; % image en noir et blanc
        box; %coordonnéees du cadre autour du code barre
        barcode_rotate; %image noir et blanc recadrée et orientée
        i_regions_petites; %image après passage dans tous les filtres
        i_regions_allongees; %image après passage du permier filtre sur les régions allongées
        validBarcode;
        stats3; %Données sur les régions composées par les barres du code
        r; %barcode (char)
        url; %web link
        camOff=1;%La webcam est éteinte
        cam;
        isrunning=0; %Booléen correspondant au traitement en cours ou non d'une image (Application lente, on tente de limiter les opérations lancées en parallèle.) 
    end

    
    methods (Access = private)
        
        function preTraitement(app,image)
            app.isrunning=1; %On lance le traitement d'une image
            %conversion en nuance de gris
            i_gray = rgb2gray(image);
            % Filtrage morphologique top hat black (mise en évidence du contraste)
            se = strel('square',100);
            i_black = imbothat(i_gray,se);
            %Augmentation du contraste
            i_adjust = imadjust(i_black);
            %conversion en noir et blanc
            app.i_bw = imbinarize(i_adjust);
            %extraction du code barre
            filtre1(app)
        end
        
        % Filtrage des régions allongées (rapport longeur/largeur des axes principaux > 10)
        function filtre1(app)
            %numérotation des régions connexes
            [i_regions,n_regions] = bwlabel(app.i_bw,4);
            %calcul des paramètres géométriques des régions
            stats1 = regionprops(i_regions,'MinorAxisLength','MajorAxisLength');
            
            d=zeros(length(stats1));
            %pour chaque région d'indice k
            for k=1:length(stats1)
                if (stats1(k).MajorAxisLength/stats1(k).MinorAxisLength) > 5
                    d(k)=k;
                end
            end
            e=d~=0;
            idx_regions_allongees = d(e);
            
            %creation d'une image noir et blanc avec les régions allongées uniquement
            app.i_regions_allongees = ismember(i_regions,idx_regions_allongees);
            
            filtre2(app,app.i_regions_allongees);
        end
        
        % Filtrage des régions de meme orientation
        function filtre2(app,i_regions_allongees)
            %numérotation des régions allongées
            i_regions_allongees_num = bwlabel(i_regions_allongees);
            stats2=regionprops(i_regions_allongees_num,'Centroid','Orientation');

            orientation_identique=zeros(length(stats2));
            a=zeros(1,length(stats2));
            for i=1:length(stats2)
                for j=1:length(stats2)
                    if isSameOrientation(stats2(i).Orientation,stats2(j).Orientation)
                        orientation_identique(i,j) = 1;
                    end
                end
                a(i)=sum(orientation_identique(i,:));
            end
            ligne=1;
            for i=2:length(a)
                if a(i)>a(ligne)
                    ligne=i;
                end
            end
            b=zeros(length(orientation_identique));
            for i=1:length(orientation_identique)
                if orientation_identique(ligne,i)
                    b(i)=i;
                end
            end
            c=b~=0;
            idx_orientation_identique = b(c);
            %creation d'une image noir et blanc avec les régions allongées de meme orientation uniquement
            i_regions_orientation_identique = ismember(i_regions_allongees_num,idx_orientation_identique);
            
            filtre3(app,i_regions_orientation_identique);
        end
        
        % Filtrage des régions possédant des régions voisines proches
        function filtre3(app,i_regions_orientation_identique)
            %numérotation des régions de meme orientation
            i_regions_orientation_identique_num = bwlabel(i_regions_orientation_identique);
            app.stats3=regionprops(i_regions_orientation_identique_num,'Centroid','Orientation');
            Distance=zeros(1,length(app.stats3));
            Taille=size(app.i_bw);
            DimensionIm=Taille(1,1);
            
            for i=1:length(app.stats3)
                Position_i=app.stats3(i).Centroid;
                voisins=DimensionIm*ones(1,5); %On cherche les 5 voisins les plus proches
                
                for j=1:length(app.stats3)
                    dist=DimensionIm;
                    if i~=j
                        Position_j=app.stats3(j).Centroid;
                        dist=sqrt((Position_i (1,1)-Position_j (1,1))^2 + (Position_i (1,2)-Position_j (1,2))^2);
            
                        if(dist<voisins(1,5))
                            voisins(1,5)=dist;
                            voisins=sort(voisins);               
                        end
                        
                    end
                        
                end
                
            Distance(1,i)=sum(voisins);
                
            end
            
            Minimum=min(Distance);
            idx=[];
            
            for i=1:length(Distance)
                if Distance(i)<2.25*Minimum   %Le 2.25 est déterminé expérimentalement. Pour une valeur inférieure on commence à supprimer les barres extérieures du code
                idx=[idx i];
                end
            end
            %creation d'une image noir et blanc avec les régions voisines uniquement
            i_regions_voisines = ismember(i_regions_orientation_identique_num,idx);
            
            %%Filtrage sur les tailles
            %A ce stade, il peut rester quelques régions issues de lettres d'un texte
            %On propose de les éliminer en les comparant à la taille médiane des
            %régions restantes, celle des barres du code

            stats1_2 = regionprops(i_regions_voisines,'MajorAxisLength','Centroid');
            Med=[];
            d=zeros(length(stats1_2));

            for k=1:length(stats1_2)
            Med=[Med (stats1_2(k).MajorAxisLength)];
            end
            Med=median(Med); 
            %La moyenne n'aurait ici pas d'intéret, les petites barres la tirant vers le bas, il serait difficile de les filtrer
            %Si le travail a été bien fait jusqu'ici, les régions restantes sont
            %majoritairement celles du code-barres: la médiane correspond à la taille
            %d'une barre. (Elle pourrait aussi correspondre à une barre coupée en deux,
            %d'où les marges élargies dans la boucle suivante.

            for k=1:length(stats1_2)
                    if (((stats1_2(k).MajorAxisLength)<3*Med)&&((stats1_2(k).MajorAxisLength)>Med/5))
                         d(k)=k;       
                    end
            end

e=d~=0;
idx_regions_petites = d(e);

%creation d'une image noir et blanc avec les régions petites uniquement
app.i_regions_petites = ismember(i_regions_voisines,idx_regions_petites);
%numérotation des régions du code-barres
app.i_regions_petites = bwlabel(app.i_regions_petites);

            
            % Calcul de la zone ou se trouve le code barre
            app.box = findBoundingBox(app.i_regions_petites);
            
            % Sélection de la zone ou se trouve le code barre
            %On n'utilise pas les images avec le plus de filtres car on
            %peut perdre des barres du code. Ces filtres permettent de
            %cadrer l'image autour du code
            barcode_crop=imcrop(app.i_bw,app.box);
            %Rotation du code barre
            angle_moy = app.stats3(1).Orientation+90;
            for i=2:length(app.stats3)
                angle_i = app.stats3(i).Orientation+90;
                if abs(angle_moy-angle_i)>90
                    angle_moy = mod((angle_moy+angle_i+180)/2,180);
                else
                    angle_moy = (angle_moy+angle_i)/2;
                end
            end
            angle_moy = 180-angle_moy;
            
            app.barcode_rotate = imrotate(barcode_crop,angle_moy);
            %les barres sont verticales mais elles peuvent être à l'envers
            decodage(app);
        end
        
        function decodage(app)
            app.validBarcode = 0;
            
l=0;            
while l<3  
%Cette boucle permet d'essayer de déchiffrer trois images différentes:
%L'étape de redressement peut créer des problèmes lors de la projection:
%Si la première barre est coupée en deux, on ne considèrera qu'une moitié
%et la projection rendra le code illisible. 
%On essaie donc d'abord avec une image traitée mais non redressée.
%Puis avec une image regions_allongees redressée et enfin avec une image
%regions_petites redressée.  

%Cas l==1, on reprend l'image app.barcode définie précédement. 
%Dans la fonction Redresse utilisé pour les cas l==2 et 3, on fait des moyennes colonne
%par colonne pour définir des colonnes blanches et noires et obtenir le
%code le plus propre possible. Ici on ne réalise pas cette étape
%d'homogénéisation des colonnes car on ne projette pas: les colonnes
%peuvent être pour cetraines obliques, des moyennes pas colonnes seraient
%inutiles. 

l=l+1; %On veut que la boucle while finisse

if l==2
    %%Redressement des photos prises de biais
    disp('je suis en 2!')
    app.barcode_rotate=Redresse(imcrop(app.i_regions_petites,app.box),app.stats3);
end

if l==3
    %%Redressement des photos prises de biais   
    app.barcode_rotate=Redresse(imcrop(app.i_regions_allongees,app.box),app.stats3);
end


            for i=1:size(app.barcode_rotate,1)
                %extraction d'une ligne
                ligne=extractLigne(i,app.barcode_rotate);
            
                %calcul le nombre de barres verticales
                numberOfBars=countBars(ligne);
                
                %tableau contenant la largeur des barres
                widthOfBars=findWidths2(ligne,numberOfBars);
            
                %donne l'indice de la premiere barre valide (k)
                %et de la dernière barre valide (g)
                [k,g]=findValidBars(widthOfBars);
                
                 if (numberOfBars-g-k)==57

        %calcul la largeur des barres élémentaires
        standardWidth1=(widthOfBars(k)+widthOfBars(k+1)+widthOfBars(k+2))/3;
        standardWidth2=(widthOfBars(k+27)+widthOfBars(k+28)+widthOfBars(k+29)+widthOfBars(k+30)+widthOfBars(k+31))/5;
        standardWidth3=(widthOfBars(k+56)+widthOfBars(k+57)+widthOfBars(k+58))/3;
    
        %applatissement du code barre et normalisation
        x1=[k+1, k+29, k+57];
        y1=[standardWidth1, standardWidth2, standardWidth3];
        p=polyfit(x1,y1,2);
    
        x=k:k+58;
    
        standardWidthOfBars(x)=round(widthOfBars(x)./polyval(p,x));
        
         %decodage du code barre
        [app.validBarcode,result]=decode(standardWidthOfBars,k);
                
                %verification de la validité du code barre(chiffre de controle)
                if app.validBarcode
                    app.r = num2str(result);
                    app.Label.Text = app.r;
                    dispWebPage(app);
                    dispInfo(app);
                    app.camOff=1;
                    l=5;%On n'essaie pas d'autres images
                    break;
                else
                    [app.validBarcode,result]=decode(fliplr(standardWidthOfBars),1);
                    %On retourne l'image à 180 dans le cas où elle serait à
                    %l'envers
                    if app.validBarcode
                        app.r = num2str(result);
                        app.Label.Text = app.r;
                        dispWebPage(app);
                        dispInfo(app);
                        app.camOff=1;
                        l=5; 
                        break;
                    end
                end

                 end
            end %fin de boucle for
            
            if ((l==1)&&(app.camOff==0))
              %Si l'image est issue de la webcam on favorise la prise de plusieurs images
              %Donc on ne réalise pas d'essais sur des images redressées,
              %ce qui ralentirait le code. 
              l=5;
            end
            
            end %fin de boucle while
            
                    if ~app.validBarcode
                app.Label.Text = 'décodage impossible';
                hideInfo(app);
                    end
        app.isrunning=0; %On a fini le décodage, réussi ou non
        end
        
        
        function dispWebPage(app)
            app.url = strcat('https://fr.openfoodfacts.org/produit/',app.r);
            if app.validBarcode && app.openfoodfactswebpageCheckBox.Value
                web(app.url);
            end
        end
        
        function dispInfo(app)
            %titre du produit
            sourceCode = webread(app.url);
            idx1 = regexp(sourceCode,'<title>');
            idx2 = regexp(sourceCode,'</title>');
            title=sourceCode(idx1+7:idx2-1);
            app.TextArea.Value =title;
            %image du produit
            [startIndex,endIndex] = regexp(sourceCode,'<meta name="twitter:image" content="');
            startIndex=endIndex+1;
            while sourceCode(endIndex:endIndex+1)~='>'
                endIndex=endIndex+1;
            end
            endIndex=endIndex-1;
            imagepath = sourceCode(startIndex:endIndex);
            i=imread(imagepath);
            imshow(i, 'Parent', app.UIAxes2);
        end
        
        function hideInfo(app)
            app.TextArea.Value=' ';
            imshow([], 'Parent', app.UIAxes2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.validBarcode=0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            [FILENAME, PATHNAME] = uigetfile('*.*');
            i_rgb=imread(strcat(PATHNAME,FILENAME));
            imshow(i_rgb, 'Parent', app.UIAxes);
            preTraitement(app,i_rgb);
        end

        % Value changed function: openfoodfactswebpageCheckBox
        function openfoodfactswebpageCheckBoxValueChanged(app, event)
            value = app.openfoodfactswebpageCheckBox.Value;
            if ~value
                com.mathworks.mlservices.MatlabDesktopServices.getDesktop.closeGroup('Web Browser')
            else
                dispWebPage(app);
            end
        end

        % Button pushed function: StartcameraButton
        function StartcameraButtonPushed(app, event)

            app.cam = webcam;
            app.camOff=0;
            %On prend une photo à décoder
            while app.camOff==0
                disp(app.camOff)
                %Seulement si la précédente a déjà été traitée
                if app.isrunning==0
                    disp('photo')
                i=snapshot(app.cam);
                preTraitement(app,i);
                imshow(i, 'Parent', app.UIAxes);
                %On montre, au centre de l'application, les différentes
                %photos prises par la webcam pour permettre à l'utilisateur
                %de cadrer le code-barres
                %On ne montre pas une video de ce que filme la webcam car
                %c'est trop lourd: l'application ne fonctionne plus de
                %façon fluide... 
                end
            end
            delete(app.cam);
        end

        % Button pushed function: StopcameraButton
        function StopcameraButtonPushed(app, event)
            app.camOff=1;
            %delete(app.cam);
            imshow([], 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MenuLabel = uilabel(app.LeftPanel);
            app.MenuLabel.Text = 'Menu';
            app.openfoodfactswebpageCheckBox = uicheckbox(app.LeftPanel);
            app.openfoodfactswebpageCheckBox.ValueChangedFcn = createCallbackFcn(app, @openfoodfactswebpageCheckBoxValueChanged, true);
            app.openfoodfactswebpageCheckBox.Text = 'openfoodfacts web page';
            app.LoadimageButton = uibutton(app.LeftPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.StartcameraButton = uibutton(app.LeftPanel, 'push');
            app.StartcameraButton.ButtonPushedFcn = createCallbackFcn(app, @StartcameraButtonPushed, true);
            app.StartcameraButton.Text = 'Start camera';
            app.StopcameraButton = uibutton(app.LeftPanel, 'push');
            app.StopcameraButton.ButtonPushedFcn = createCallbackFcn(app, @StopcameraButtonPushed, true);
            app.StopcameraButton.Text = 'Stop camera';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.EAN13BarcodeReaderLabel = uilabel(app.GridLayout2);
            app.EAN13BarcodeReaderLabel.Text = 'EAN 13 Barcode Reader';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '';
            app.BarcodeLabel = uilabel(app.GridLayout2);
            app.BarcodeLabel.Text = 'Barcode :';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.ResultLabel = uilabel(app.GridLayout3);
            app.ResultLabel.Text = 'Result';
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.Visible = 'off';
            app.TextArea = uitextarea(app.GridLayout3);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BarcodeReader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end